classdef Aplikace_robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        YlimEditField_3               matlab.ui.control.EditField
        YlimEditField_3Label          matlab.ui.control.Label
        XlimEditField_4               matlab.ui.control.EditField
        XlimEditField_4Label          matlab.ui.control.Label
        Ylim2EditField                matlab.ui.control.EditField
        Ylim2EditFieldLabel           matlab.ui.control.Label
        RychlostfrekvenceButton       matlab.ui.control.Button
        FrekvenceButton               matlab.ui.control.Button
        YlimEditField_2               matlab.ui.control.EditField
        YlimEditField_2Label          matlab.ui.control.Label
        XlimEditField_3               matlab.ui.control.EditField
        XlimEditField_3Label          matlab.ui.control.Label
        XlimEditField_2               matlab.ui.control.EditField
        XlimEditField_2Label          matlab.ui.control.Label
        YlimEditField                 matlab.ui.control.EditField
        YlimEditFieldLabel            matlab.ui.control.Label
        XlimEditField                 matlab.ui.control.EditField
        XlimEditFieldLabel            matlab.ui.control.Label
        NvodTextArea                  matlab.ui.control.TextArea
        NvodTextAreaLabel             matlab.ui.control.Label
        NastavitelnparametryTextArea  matlab.ui.control.TextArea
        NastavitelnparametryLabel     matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        RminEditField                 matlab.ui.control.NumericEditField
        RminEditFieldLabel            matlab.ui.control.Label
        Start2Button                  matlab.ui.control.Button
        knsobky20k20Label             matlab.ui.control.Label
        Lamp_2                        matlab.ui.control.Lamp
        FolderPathEditField           matlab.ui.control.EditField
        FolderPathEditFieldLabel      matlab.ui.control.Label
        VybersekvenciButton           matlab.ui.control.Button
        CosobjektuButton              matlab.ui.control.Button
        kEditField                    matlab.ui.control.NumericEditField
        kEditFieldLabel               matlab.ui.control.Label
        RychlostkapkyButton           matlab.ui.control.Button
        ObjektblzkoButton             matlab.ui.control.Button
        ObjektButton                  matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        PolomrEditFieldLabel          matlab.ui.control.Label
        PolomrEditField               matlab.ui.control.NumericEditField
        hodnotazEditField             matlab.ui.control.NumericEditField
        hodnotazEditFieldLabel        matlab.ui.control.Label
        KapkablzkoButton              matlab.ui.control.Button
        KapkaButton                   matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        PoziceCTRLVEditField          matlab.ui.control.EditField
        PoziceCTRLVEditFieldLabel     matlab.ui.control.Label
        VybervideoButton              matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vid % Description
        vid2
        htabulka1
        htabulka2
        htabulka3
        htabulka4
        htabulka5
        vtabulka1
        vtabulka2
        ctabulka1
        ctabulka2 
        im
        ftabulka1
        ftabulka2
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VybervideoButton
        function Vyber_video(app, event)
    [file, path] = uigetfile('*.avi','*.mp4','Select a Text File');
    if isequal(file, 0)
    disp('User selected cancel');
    else
    disp(['User selected ', fullfile(path,file)]);
    %Displaying Video on Axes
    input_video_file = fullfile(path,file);
    vid = VideoReader(input_video_file);
    app.vid=vid
    end
   

    kc=50000;

    hasFrame(vid)
        framec=readFrame(vid); 
        numberOfFramesc = vid.NumFrames;
        framesToReadc = 1:kc:numberOfFramesc;
        thisFramec = read(vid,framesToReadc(1));
        imtool(thisFramec)
        end

        % Callback function
        function Zvolit_pozici(app, event)
            pos=app.PoziceCTRLVEditField.Value;
        end

        % Button pushed function: StartButton
        function Start(app, event)
app.Lamp.Color='red'
drawnow()
vid=app.vid;
k=app.kEditField.Value;
%feval
u=[];
    while hasFrame(vid)
        frame=readFrame(vid); %přečte frames a uloží je --> to je to s čím bych měla pracovat
        numberOfFrames = vid.NumFrames; %počet 
        framesToRead = 1:k:numberOfFrames; %Rozpočítá frames s určitým krokem k


            for h = 1:length(framesToRead) %h=1 --> první krok, platí od 1 do počtu těch framů


                  %pokud to platí, aplikuje na každý snímek danou segmentaci níže
                       thisFrame = read(vid, framesToRead(h));
                       pos1=app.PoziceCTRLVEditField.Value;
                       pos=str2num(pos1);
                       cr=imcrop(thisFrame,pos);
             

                       %OBJEKT
                      
                       RPokus=im2gray(cr);
                       nHood=true(3); 
                       Rpokus=rangefilt(RPokus,nHood);
%                        imshow(Rpokus);
                       Rpokus=medfilt2(Rpokus,[7 7]);
                       Rpokus=imbinarize(Rpokus);
                       Rpokus=imfill(Rpokus,"holes");
                       SE=strel("cube",14);
                       Rpokus=imclose(Rpokus,SE);
                       Rpokus=imclearborder(Rpokus);
                       Rpokus = bwareaopen(Rpokus, 300); %vše tvořený méně než "n" pixely se odstraní
                       %Rpokus=bwareafilt(Rpokus,1);
                       imshow(Rpokus);
% 
                       %určuji interval pravděpodobného průměru
%                         Rmin = 6; %20-40 normálně, pro malou 6-40
                        Rmin=app.RminEditField.Value;
                        Rmax = 60;
                        %přes fci najdu střed a poloměr

                        [centersBright, radiiBright] = imfindcircles(Rpokus,[Rmin Rmax],'ObjectPolarity','bright');
                        a=viscircles(centersBright, radiiBright,'Color','b');
                        rp=radiiBright; %v pixelech
                        Sp=centersBright;
%                        

                         if isempty(rp) %určí prázdnou hodnotu a přeskočí ji
                                continue
                         end

                        r=rp(1)+2; %PODMÍNKA PRO VYBRÁNÍ JEDNOHO
                        %KRUHU, BOHUŽEL NASTANE CHYBA POKUD NĚKTERÁ HODNOTA 
                        %r=rp;
                        S=[centersBright(1,1),centersBright(1,2)]; %předešla jsem tomu, aby to bralo dva kruhy, občas totiž viscircles přečetl vlas jako kruh
                        roi = images.roi.Circle(gca, 'Center' ,S, 'Radius' ,r); %označení kruhu a jeho vymazání - mask
                        mask=createMask(roi);
                        Rpokus(mask)=0;
                        imshow(Rpokus);
                        [x,y]=find(Rpokus);
                        xvlas=mean(x);
                        yvlas=mean(y);
                        u=[u;h];

% KAPKA I OBJEKT
                    
                    tabulka = [xvlas,yvlas,S];


%JENOM KAPKA BEZ OBJEKTU
                             
%                                 tabulka=[Sp,Sp,Sp]

                    if h == u(1)
                        htabulka = tabulka;
                    else
                        htabulka = [htabulka; tabulka];
                    end
            end
                                 
        if h==length(framesToRead)
                      break
        end  
    end
htabulka=rmmissing(htabulka);

table = height(htabulka)-1;
 for e=1:table %protože h=velikosti tabulky table 
     if e<table
    t=(e-1)*k/20;
    tv=k/20; %nebo stačí napsat, že je vždy k/20
    n=e+1;
    
    dx=htabulka(n,3)-htabulka(e,3);
    dy=htabulka(n,4)-htabulka(e,4);
            
    r=app.PolomrEditField.Value;
    s=sqrt(dx^2+dy^2);
    v1=s/tv;
    v=v1*24/r/2;

     tab = [t,v];
     if e == 1
        vtabulka = tab;
     else
        vtabulka = [vtabulka; tab];
     end

   if e==table-1
      break
   end  
     end
 end

vtabulka=rmmissing(vtabulka);
vtabulka;


tab = height(htabulka)-1; %podmínka
for x = 1:table
    cas=x*k/20; %jen pro kontrolu
    if x<=tab
    y=x+1;
    Salfa=[htabulka(x,3),htabulka(x,4)]; %střed
    b1=[htabulka(x,1),htabulka(x,2)]; %bod 1
    b2=[htabulka(y,1),htabulka(y,2)]; %bod 2
    atan = atan2(abs(det([b2-Salfa;b1-Salfa])),dot(b2-Salfa,b1-Salfa)); %úhel
    ang= atan*180/pi; %přepočet na stupně
    ang= rmmissing(ang); % Zajišťuje odstranění Nan hodnoty (počáteční položení objektu, tam není) 
    
    cosf=cos(ang);
        
       tabl =[ang];
      if x == 1
         utabulka = tabl;
      else
         utabulka = [utabulka; tabl];
      end
    
    
    end
end
a=height(utabulka);
for tcas=1:a %aby tabulky měly stejný počet řádků jak s časem tak s úhlem
    cas=tcas*k/20; %výpočet času
    tabc =[cas]; %přidávám další tabulku, při první tabulce kvůli nan posunuté hodnoty
      if tcas == 1
         ttabulka = tabc;
      else
         ttabulka = [ttabulka; tabc];
      end
end

% sum=sum(utabulka(:,2), 'omitnan' );
podm = height(utabulka);
 for z=1:x
     if z<=podm
     w=cumsum(utabulka(:,1)); %sčítá hodnoty (posloupnost)
     cas=ttabulka(:,1); %tabulka s časem
     cosf=cosd(w); %tabulka s úhlem přepočtšným na cosinus
     
     tab2=([cas,cosf]);
     tab3=([cas,w]);
      if z == 1
         ctabulka = tab2;
         c1tabulka=tab3;
      else
         ctabulka = [ctabulka; tab2];
         c1tabulka= [c1tabulka;tab3];
      end
     
     end

 end
c1tabulka;
ctabulka=rmmissing(ctabulka);
c1tabulka=rmmissing(c1tabulka);
nu=c1tabulka(end,end); %zjistím si poslední hodnotu tabulky
nu_pocet=nu/360 %teď vím, kolikrát se to otočí
tabnas=[360, 720, 1080, 1440, 1800, 2160, 2520, 2880, 3240, 3600, 3960, 4320, 4680, 5040, 5400, 5760, 6120, 6480, 6840, 7200, 7560, 7920, 8280, 8640, 9000, 9360, 9720, 10080, 10440, 10800, 11160, 11520, 11880, 12240, 12600, 12960, 13320, 13680, 14040, 14400, 14760, 15120, 15480, 15840, 16200, 16560, 16920, 17280, 17640, 18000, 18360, 18720, 19080, 19440, 19800, 20160, 20520, 20880, 21240, 21600, 21960, 22320, 22680, 23040, 23400, 23760, 24120, 24480, 24840, 25200, 25560, 25920, 26280, 26640, 27000, 27360, 27720, 28080, 28440, 28800, 29160, 29520, 29880, 30240, 30600, 30960, 31320, 31680, 32040, 32400, 32760, 33120, 33480, 33840, 34200, 34560, 34920, 35280, 35640, 36000];
nuz=floor(nu_pocet); %zaokrouhluju na nejmenší celé číslo
beru=tabnas(1:nuz); %vybere jen počet potřebných násobků

% Najděte nejbližší hodnotu k dané hodnotě
rozdil = abs(c1tabulka(:,2) - beru);
[~, index] = min(rozdil);
nejblizsi_hodnota = c1tabulka(index,2);
ncas = c1tabulka(index,1);
ncas1=[0;ncas];
hodn=nejblizsi_hodnota/360;
for f=1:nuz
    casf=ncas1(f+1)-ncas1(f); %Potřebuju vědět čas otočení
    frek=1/casf;
    omega=frek*2*pi;
    tab4=[omega];
      if f == 1
         frtabulka=tab4;
      else
         frtabulka = [frtabulka; tab4];
      end  
     
end

ftabulka=[ncas,frtabulka];


app.Lamp.Color = 'green';
drawnow()


% DETEKCE ČISTÉ KAPKY

app.htabulka5=htabulka;
app.htabulka1=htabulka(:,3);
app.htabulka2=htabulka(:,4);
app.htabulka3=htabulka(:,1);
app.htabulka4=htabulka(:,2);
app.vtabulka1=vtabulka(:,1);
app.vtabulka2=vtabulka(:,2);
app.ctabulka1=ctabulka(:,1);
app.ctabulka2=ctabulka(:,2);
app.ftabulka1=ftabulka(:,1);
app.ftabulka2=ftabulka(:,2);

        end

        % Button pushed function: KapkaButton
        function Tr_obj_r(app, event)
r=app.PolomrEditField.Value;
axes_x = app.htabulka1;
axes_y = app.htabulka2;


plot(app.UIAxes, axes_x, axes_y)
daspect([24 24 24])
p=2;
m1=mean(app.htabulka1)+p; %abych zaručila skutečně střed celkové rotace 
m2=mean(app.htabulka2)+p;%Lze přičít hodnotu - navýšení pixelů středu, ne zápor


Dolx=m1-r; %91 je poloměr kapky --> při cropování stačí změřit
Dopx=m1+r;
Doly=m2-r;
Dopy=m2+r;
PrumD1=[Dolx,Doly]; 
PrumD2=[Dopx,Dopy];
D1=mean(PrumD1); %průměr hodnot, stejná délka os
D2=mean(PrumD2);
xl=[D1 D2];
yl=[D1 D2];
xlim(app.UIAxes,xl)
ylim(app.UIAxes,yl)

        end

        % Button pushed function: KapkablzkoButton
        function Traj_kap_focus(app, event)
z=app.hodnotazEditField.Value;
axes_x = app.htabulka1;
axes_y = app.htabulka2;


plot(app.UIAxes,axes_x, axes_y)
p=2;
m3=mean(app.htabulka1)+p; %abych zaručila skutečně střed celkové rotace 
m4=mean(app.htabulka2)+p; %Lze přičít hodnotu - navýšení pixelů středu, ne zápor
Dolx1=m3-z; %90 je poloměr kapky --> při cropování stačí změřit
Dopx1=m3+z;
Doly1=m4-z;
Dopy1=m4+z;
PrumD3=[Dolx1,Doly1]; 
PrumD4=[Dopx1,Dopy1];
D3=mean(PrumD3); %průměr hodnot, stejná délka os
D4=mean(PrumD4);
x2=[D3 D4];
y2=[D3 D4];
xlim(app.UIAxes,x2)
ylim(app.UIAxes,y2)


        end

        % Value changed function: PolomrEditField
        function polomer(app, event)
            r= app.PolomrEditField.Value;
            
        end

        % Button pushed function: ObjektButton
        function Traj_objekt(app, event)
r=app.PolomrEditField.Value;
axes_x1 = app.htabulka3;
axes_y1 = app.htabulka4;

plot(app.UIAxes,axes_x1, axes_y1)
p=2;
m5=mean(app.htabulka3)+p; %abych zaručila skutečně střed celkové rotace 
m6=mean(app.htabulka4)+p; %Lze přičít hodnotu - navýšení pixelů středu, ne zápor
Dolx2=m5-r; %91 je poloměr kapky --> při cropování stačí změřit
Dop2=m5+r;
Doly2=m6-r;
Dopy2=m6+r;
PrumD5=[Dolx2,Doly2]; 
PrumD6=[Dop2,Dopy2];
D5=mean(PrumD5); %průměr hodnot, stejná délka os
D6=mean(PrumD6);
x3=[D5 D6];
y3=[D5 D6];
xlim(app.UIAxes,x3)
ylim(app.UIAxes,y3)


        end

        % Button pushed function: ObjektblzkoButton
        function Traj_objekt_focus(app, event)
z=app.hodnotazEditField.Value;
axes_x1 = app.htabulka3;
axes_y1 = app.htabulka4;
plot(app.UIAxes,axes_x1, axes_y1)
p=2;
m7=mean(app.htabulka3)+p; %abych zaručila skutečně střed celkové rotace 
m8=mean(app.htabulka4)+p; %Lze přičít hodnotu - navýšení pixelů středu, ne zápor
Dolx3=m7-z; %91 je poloměr kapky --> při cropování stačí změřit
Dopx3=m7+z;
Doly3=m8-z;
Dopy3=m8+z;
PrumD7=[Dolx3,Doly3]; 
PrumD8=[Dopx3,Dopy3];
D7=mean(PrumD7); %průměr hodnot, stejná délka os
D8=mean(PrumD8);
x4=[D7 D8];
y4=[D7 D8];
xlim(app.UIAxes,x4)
ylim(app.UIAxes,y4)

        end

        % Button pushed function: RychlostkapkyButton
        function Rychlost_kapky(app, event)
     
axes_x = app.vtabulka1;
axes_y = app.vtabulka2;
plot(app.UIAxes2,axes_x, axes_y)
xlabel("t [s]");
ylabel("v [mm/s]");
xl=app.XlimEditField.Value;
xl=str2num(xl);
yl=app.YlimEditField.Value;
yl=str2num(yl);
xlim(app.UIAxes2,xl)
ylim(app.UIAxes2,yl)


        end

        % Button pushed function: CosobjektuButton
        function Cos_objektu(app, event)

axes_x = app.ctabulka1;
axes_y = app.ctabulka2;
[axes_x, dum] = sort(axes_x); %aby se nepropojovala první a poslední hodnota
axes_y = axes_y(dum);
plot(app.UIAxes3,axes_x, axes_y)
xlabel("t [s]")
ylabel("Cos [alfa]")
ylim(app.UIAxes3,[-1 1])
xlc=app.XlimEditField_2.Value;
xlc=str2num(xlc);
xlim(app.UIAxes3,xlc);
        end

        % Button pushed function: VybersekvenciButton
        function Vyber_sekvenci(app, event)
    file = uigetdir(); % otevření dialogového okna pro výběr složky
    if file ~= 0 % pokud uživatel vybral složku
        app.FolderPathEditField.Value = file; % nastavení hodnoty v editovatelném poli
        b = dir(fullfile(file,'*.jpg')); % uložení seznamu souborů do vlastnosti "FileList"
    end
     
    
    filename=fullfile(file,b(1).name);
    x=imread(filename);
    imtool(x)
    
        end

        % Button pushed function: Start2Button
        function Start2(app, event)
app.Lamp_2.Color='red';
drawnow()
file=app.FolderPathEditField.Value;
b = dir(fullfile(file,'*.jpg'));
n = numel(b);
k=app.kEditField.Value;
kr=k/20;
u=[];
 
            for h = 1:kr:n %h=1 --> první krok, platí od 1 do počtu těch framů
                filename=fullfile(file,b(h).name);
                x1=imread(filename);

                       pos2=app.PoziceCTRLVEditField.Value;
                       pos1=str2num(pos2);
                       cr=imcrop(x1,pos1);

%                        %imshow(cr)
                       RPokus1=im2gray(cr);
                       nHood=true(3); 
                       Rpokus1=rangefilt(RPokus1,nHood);
%                        imshow(Rpokus);
                       Rpokus1=medfilt2(Rpokus1,[7 7]);
                       Rpokus1=imbinarize(Rpokus1);
                       Rpokus1=imfill(Rpokus1,"holes");
                       SE=strel("cube",14);
                       Rpokus1=imclose(Rpokus1,SE);
                       Rpokus1=imclearborder(Rpokus1);
                       Rpokus1 = bwareaopen(Rpokus1, 300); %vše tvořený méně než "n" pixely se odstraní
                       %Rpokus=bwareafilt(Rpokus,1);
                       imshow(Rpokus1);
% 
                       %určuji interval pravděpodobného průměru
                        Rmin=app.RminEditField.Value; %20-40 normálně, pro malou 6-40
                        Rmax = 70;
                        %přes fci najdu střed a poloměr

                        [centersBright, radiiBright] = imfindcircles(Rpokus1,[Rmin Rmax],'ObjectPolarity','bright');
                        a=viscircles(centersBright, radiiBright,'Color','b');
                        rp=radiiBright; %v pixelech
                        Sp=centersBright;


                         if isempty(rp) %určí prázdnou hodnotu a přeskočí ji
                                continue
                         end
                        r=rp(1)+2; %PODMÍNKA PRO VYBRÁNÍ JEDNOHO
                        %KRUHU, BOHUŽEL NASTANE CHYBA POKUD NĚKTERÁ HODNOTA 
                        %r=rp;
                        S1=[centersBright(1,1),centersBright(1,2)]; %předešla jsem tomu, aby to bralo dva kruhy, občas totiž viscircles přečetl vlas jako kruh
                        roi = images.roi.Circle(gca, 'Center' ,S1, 'Radius' ,r); %označení kruhu a jeho vymazání - mask
                        mask=createMask(roi);
                        Rpokus1(mask)=0;
                        imshow(Rpokus1);
                        [x1,y]=find(Rpokus1);
                        xvlas1=mean(x1);
                        yvlas1=mean(y);
                        u=[u;h];

                        
%KAPKA I OBJEKT                   
                    tabulka1=[xvlas1,yvlas1,S1];

%JENOM KAPKA BEZ OBJEKTU

%                   tabulka1=[Sp,Sp,Sp]

                    if h==u(1)
%                     if h==1
                        htabulka = tabulka1;

                    else
                        htabulka = [htabulka; tabulka1];
                    end
                                         
                    if h==n
                          break
                    end  
           end
htabulka=rmmissing(htabulka);
htabulka;
table = height(htabulka)-1;
 for e=1:table %protože h=velikosti tabulky table 
     if e<table
    t=(e-1)*k/20;
    tv=k/20; %nebo stačí napsat, že je vždy k/20
    n=e+1;
    
    dx=htabulka(n,3)-htabulka(e,3);
    dy=htabulka(n,4)-htabulka(e,4);
        
    
    r=app.PolomrEditField.Value;
    s=sqrt(dx^2+dy^2);
    v1=s/tv;
    v=v1*24/r/2;

     tab = [t,v];
     if e == 1
        vtabulka = tab;
     else
        vtabulka = [vtabulka; tab];
     end

   if e==table-1
      break
   end  
     end
end
vtabulka=rmmissing(vtabulka);
vtabulka;

tab = height(htabulka)-1; %podmínka
for x1 = 1:table
    cas=x1*k/20; %jen pro kontrolu
    if x1<=tab
    y=x1+1;
    Salfa=[htabulka(x1,3),htabulka(x1,4)]; %střed
    b1=[htabulka(x1,1),htabulka(x1,2)]; %bod 1
    b2=[htabulka(y,1),htabulka(y,2)]; %bod 2
    atan = atan2(abs(det([b2-Salfa;b1-Salfa])),dot(b2-Salfa,b1-Salfa)); %úhel
    ang= atan*180/pi; %přepočet na stupně
    ang= rmmissing(ang); % Zajišťuje odstranění Nan hodnoty (počáteční položení objektu, tam není) 
    
    cosf=cos(ang);
        
       tabl =[ang];
      if x1 == 1
         utabulka = tabl;
      else
         utabulka = [utabulka; tabl];
      end
   
    
    end
end
a=height(utabulka);
for tcas=1:a %aby tabulky měly stejný počet řádků jak s časem tak s úhlem
    cas=tcas*k/20; %výpočet času
    tabc =[cas]; %přidávám další tabulku, při první tabulce kvůli nan posunuté hodnoty
      if tcas == 1
         ttabulka = tabc;
      else
         ttabulka = [ttabulka; tabc];
      end
end
utabulka;
ttabulka;
% sum=sum(utabulka(:,2), 'omitnan' );
podm = height(utabulka);
 for z=1:x1
     if z<=podm;
     w=cumsum(utabulka(:,1)); %sčítá hodnoty (posloupnost)
     cas=ttabulka(:,1); %tabulka s časem
     cosf=cosd(w); %tabulka s úhlem přepočtšným na cosinus
     
     tab2=([cas,cosf]);
     tab3=([cas,w]);
      if z == 1
         ctabulka = tab2;
         c1tabulka=tab3;
      else
         ctabulka = [ctabulka; tab2];
         c1tabulka= [c1tabulka;tab3];
      end
     
     end

 end
c1tabulka
% ctabulka=rmmissing(ctabulka);
% c1tabulka=rmmissing(c1tabulka);
nu=c1tabulka(end,end); %zjistím si poslední hodnotu tabulky
nu_pocet=nu/360 %teď vím, kolikrát se to otočí
tabnas=[360, 720, 1080, 1440, 1800, 2160, 2520, 2880, 3240, 3600, 3960, 4320, 4680, 5040, 5400, 5760, 6120, 6480, 6840, 7200, 7560, 7920, 8280, 8640, 9000, 9360, 9720, 10080, 10440, 10800, 11160, 11520, 11880, 12240, 12600, 12960, 13320, 13680, 14040, 14400, 14760, 15120, 15480, 15840, 16200, 16560, 16920, 17280, 17640, 18000, 18360, 18720, 19080, 19440, 19800, 20160, 20520, 20880, 21240, 21600, 21960, 22320, 22680, 23040, 23400, 23760, 24120, 24480, 24840, 25200, 25560, 25920, 26280, 26640, 27000, 27360, 27720, 28080, 28440, 28800, 29160, 29520, 29880, 30240, 30600, 30960, 31320, 31680, 32040, 32400, 32760, 33120, 33480, 33840, 34200, 34560, 34920, 35280, 35640, 36000];
nuz=floor(nu_pocet); %zaokrouhluju na nejmenší celé číslo
beru=tabnas(1:nuz); %vybere jen počet potřebných násobků

% Najděte nejbližší hodnotu k dané hodnotě
rozdil = abs(c1tabulka(:,2) - beru);
[~, index] = min(rozdil);
nejblizsi_hodnota = c1tabulka(index,2);
% ncas = c1tabulka(index,1);
[~, idx] = ismember(nejblizsi_hodnota, c1tabulka);
% získání odpovídajících časů z matice cas
myRows = c1tabulka(ismember(c1tabulka(:,2), nejblizsi_hodnota), :);
ncas2=myRows(:,1);
ncas = ncas2(1:nuz,:)
ncas1=[0;ncas];
hodn=nejblizsi_hodnota/360;
for f=1:nuz
    casf=ncas1(f+1)-ncas1(f); %Potřebuju vědět čas otočení
    frek=1/casf;
    omega=frek*2*pi;
    tab4=[omega];
      if f == 1
         frtabulka=tab4;
      else
         frtabulka = [frtabulka; tab4];
      end  
     
end

ftabulka=[ncas,frtabulka];
ftabulka;
filename = 'pokus.xlsx';
writematrix(ftabulka,filename,'Sheet',1,'Range','G56')

app.Lamp.Color = 'green';
drawnow()

app.Lamp_2.Color='green';
drawnow()

app.htabulka5=htabulka;
app.htabulka1=htabulka(:,3);
app.htabulka2=htabulka(:,4);
app.htabulka3=htabulka(:,1);
app.htabulka4=htabulka(:,2);
app.vtabulka1=vtabulka(:,1);
app.vtabulka2=vtabulka(:,2);
app.ctabulka1=ctabulka(:,1);
app.ctabulka2=ctabulka(:,2);
app.ftabulka1=ftabulka(:,1);
app.ftabulka2=ftabulka(:,2);
        end

        % Value changed function: Switch
        function MyToogle(app, event)
tlac = app.Switch.Value;           
if strcmp(tlac, '20')
        app.RminEditField.Value = 20;
else
    strcmp(tlac, '6')
    app.RminEditField.Value = 6;
end

        end

        % Button pushed function: FrekvenceButton
        function Frekvence(app, event)
            axes_x4 = app.ftabulka1;
            axes_y4 = app.ftabulka2;
            plot(app.UIAxes4,axes_x4, axes_y4)
            xl=app.XlimEditField_3.Value;
            xl=str2num(xl);
            yl=app.YlimEditField_2.Value;
            yl=str2num(yl);
            xlim(app.UIAxes4,xl)
            ylim(app.UIAxes4,yl)
        end

        % Button pushed function: RychlostfrekvenceButton
        function RychlostFrekvence(app, event)
            axes_x = app.vtabulka1;
            axes_y = app.vtabulka2;
            axes_xrf = app.ftabulka1;
            axes_yrf = app.ftabulka2;
            yyaxis(app.UIAxes5,"left")
            plot(app.UIAxes5,axes_x,axes_y,'color',"[0.6350 0.0780 0.1840]")
            ylim(app.UIAxes5, [0 0.5])
            yyaxis(app.UIAxes5,"right")
            plot(app.UIAxes5,axes_xrf,axes_yrf,'color',"[0 0.3470 0.7410]")
            ylim(app.UIAxes5, [0 0.5])
            xlim(app.UIAxes5, [0 6000])
           app.UIAxes5.YColor = 'k'; % Přebarvení Y osy na černou


%             x10=app.XlimEditField_4.Value;
%             x10=str2num(x10);
%             y40=app.Ylim2EditField.Value;
%             y40=str2num(y40);
%             y10=app.YlimEditField_3.Value;
%             y10=str2num(y10);
%             xlim(app.UIAxes5,x10)
%             ylim(app.UIAxes5,y40)
%             ylim(app.UIAxes5,y10)
     
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 't [s]')
            ylabel(app.UIAxes2, 'v [mm/s]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 't [s]')
            ylabel(app.UIAxes3, 'Cos(α)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes4, 't [s]')
            ylabel(app.UIAxes4, 'ω [rad/s]')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pix1.png');
            app.VybervideoButton = uibutton(app.UIFigure, 'push');
            app.VybervideoButton.ButtonPushedFcn = createCallbackFcn(app, @Vyber_video, true);
            app.VybervideoButton.Text = 'Vyber video';
            app.PoziceCTRLVEditFieldLabel = uilabel(app.UIFigure);
            app.PoziceCTRLVEditFieldLabel.Text = 'Pozice (CTRL V)';
            app.PoziceCTRLVEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Text = 'Start';
            app.KapkaButton = uibutton(app.UIFigure, 'push');
            app.KapkaButton.ButtonPushedFcn = createCallbackFcn(app, @Tr_obj_r, true);
            app.KapkaButton.Text = 'Kapka';
            app.KapkablzkoButton = uibutton(app.UIFigure, 'push');
            app.KapkablzkoButton.ButtonPushedFcn = createCallbackFcn(app, @Traj_kap_focus, true);
            app.KapkablzkoButton.Text = 'Kapka blízko';
            app.hodnotazEditFieldLabel = uilabel(app.UIFigure);
            app.hodnotazEditFieldLabel.Text = 'hodnota z';
            app.hodnotazEditField = uieditfield(app.UIFigure, 'numeric');
            app.hodnotazEditField.Value = 30;
            app.PolomrEditField = uieditfield(app.UIFigure, 'numeric');
            app.PolomrEditField.ValueChangedFcn = createCallbackFcn(app, @polomer, true);
            app.PolomrEditField.Value = 90;
            app.PolomrEditFieldLabel = uilabel(app.UIFigure);
            app.PolomrEditFieldLabel.Text = 'Poloměr';
            app.Lamp = uilamp(app.UIFigure);
            app.ObjektButton = uibutton(app.UIFigure, 'push');
            app.ObjektButton.ButtonPushedFcn = createCallbackFcn(app, @Traj_objekt, true);
            app.ObjektButton.Text = 'Objekt';
            app.ObjektblzkoButton = uibutton(app.UIFigure, 'push');
            app.ObjektblzkoButton.ButtonPushedFcn = createCallbackFcn(app, @Traj_objekt_focus, true);
            app.ObjektblzkoButton.Text = 'Objekt blízko';
            app.RychlostkapkyButton = uibutton(app.UIFigure, 'push');
            app.RychlostkapkyButton.ButtonPushedFcn = createCallbackFcn(app, @Rychlost_kapky, true);
            app.RychlostkapkyButton.Text = 'Rychlost kapky';
            app.kEditFieldLabel = uilabel(app.UIFigure);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.UIFigure, 'numeric');
            app.kEditField.Value = 300;
            app.CosobjektuButton = uibutton(app.UIFigure, 'push');
            app.CosobjektuButton.ButtonPushedFcn = createCallbackFcn(app, @Cos_objektu, true);
            app.CosobjektuButton.Text = 'Cos objektu';
            app.VybersekvenciButton = uibutton(app.UIFigure, 'push');
            app.VybersekvenciButton.ButtonPushedFcn = createCallbackFcn(app, @Vyber_sekvenci, true);
            app.VybersekvenciButton.Text = 'Vyber sekvenci';
            app.FolderPathEditFieldLabel = uilabel(app.UIFigure);
            app.FolderPathEditFieldLabel.Text = 'FolderPath';
            app.FolderPathEditField = uieditfield(app.UIFigure, 'text');
            app.Lamp_2 = uilamp(app.UIFigure);
            app.knsobky20k20Label = uilabel(app.UIFigure);
            app.knsobky20k20Label.Text = {'k = násobky 20 !!!'; 'k≥20'};
            app.Start2Button = uibutton(app.UIFigure, 'push');
            app.Start2Button.ButtonPushedFcn = createCallbackFcn(app, @Start2, true);
            app.Start2Button.Text = 'Start2';
            app.RminEditFieldLabel = uilabel(app.UIFigure);
            app.RminEditFieldLabel.Text = 'Rmin';
            app.RminEditField = uieditfield(app.UIFigure, 'numeric');
            app.RminEditField.Value = 6;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'6', '20'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @MyToogle, true);
            app.Switch.Value = '6';
            app.NastavitelnparametryLabel = uilabel(app.UIFigure);
            app.NastavitelnparametryLabel.Text = {'Nastavitelné '; 'parametry'};
            app.NastavitelnparametryTextArea = uitextarea(app.UIFigure);
            app.NastavitelnparametryTextArea.Value = {'1. krok k=300'; 'Video - vybere každý 300stý snímek videa - každých 15 sekund'; 'Snímek - 300/20, kde 20 je rychlost snímkování kamery za sekundu (změnou kamery je nutné toto číslo opravit v kódu!!!), 300/20=15, tedy vybere snímek každých 15 sekund'; ''; '2. Pozice (CTRL V) '; 'Vložíte sem vámi vybranou pozici'; ''; '3. Rmin'; 'Jedná se o poloměr kapky dekanolu/robota'; 'program by měl fungovat i bez úpravy této hodnoty, avšak pro plynulejší chod je lepší pro větší kapky (kolem 60 mikroL) nastavit hodnotu 20 - přepnutím (Lze napsat i vlastní hodnotu přímo do pole)'; 'Pozor!!! v některých případek (s použitím určitých výtaznějších objektů na kapce (niť) se programu hodnota 20 nemusí líbit, řešením je nastavit zpět hodnotu 6'; ''; '4. Poloměr'; 'Jedná se o poloměr celé kapky, je zde nastavená hodnota dle výšky kamery, jakmile se kamera posune je nutné si tuto hodnotu nastavit (při ořezávání obrázku je v nabídce také metr, pouze stačí potáhnout z jednoho konce na druhý a vydělit tuto hodnotu dvěma)'; ''; '5. hodnota z'; 'Jedná se pouze o přiblížení grafu, lze provést i kolečkem u myši)'; ''; '6. FolderPath'; 'Nenastavuje se!, při výběru složky se zde automaticky uloží její cesta'; ''; '7. Xlim/Ylim'; 'Velikost os u rychlosti kapky (udržovat formát [xxx] !!!)'; ''; 'Pokud by se změnila velikost kapky, je třeba přenastavit kalibrace v kódu. '; ''; 'Pro změnu výšky je naopak potřeba upravit Poloměr !!! --> ten se zjistí z imtool hned při kliknutí na tlačítko Vybrat video/sekvenci. Pokud by poloměr kapky neodpovídal 90, je možné ho upravit podle konkrétního pokusu '};
            app.NvodTextAreaLabel = uilabel(app.UIFigure);
            app.NvodTextAreaLabel.Text = 'Návod';
            app.NvodTextArea = uitextarea(app.UIFigure);
            app.NvodTextArea.Value = {'1. Po výběru videa/sekvence se otevře okno, kde vyberete znak oříznutí. Označíte vámi vybraný pokus, klikněte pravým tlačítkem myši a dáte "copy position". '; ''; '2. Tuto pozici následně vložíte klávesnicovou zkratkou CTRL V do pole "Pozice (CTRL V)". '; '3. Pokud potřebujete přenastavit RMin, upravte teď! (viz "Nastavitelné parametry")'; ''; '3. Nyní stikněte "Start/Start2" (Start-video, Start2 sekvence). Rozsvítí se červená kontrolka, která značí, že experiment probíhá, jakmile se přepne na zelenou, je možnost vyhodnotit grafy, pomocí tlačítek nad grafem.'; ''; ''};
            app.XlimEditFieldLabel = uilabel(app.UIFigure);
            app.XlimEditFieldLabel.Text = 'Xlim';
            app.XlimEditField = uieditfield(app.UIFigure, 'text');
            app.XlimEditField.Value = '[0 6000]';
            app.YlimEditFieldLabel = uilabel(app.UIFigure);
            app.YlimEditFieldLabel.Text = 'Ylim';
            app.YlimEditField = uieditfield(app.UIFigure, 'text');
            app.YlimEditField.Value = '[0 0.5]';
            app.XlimEditField_2Label = uilabel(app.UIFigure);
            app.XlimEditField_2Label.Text = 'Xlim';
            app.XlimEditField_2 = uieditfield(app.UIFigure, 'text');
            app.XlimEditField_2.Value = '[0 6000]';
            app.XlimEditField_3Label = uilabel(app.UIFigure);
            app.XlimEditField_3Label.Text = 'Xlim';
            app.XlimEditField_3 = uieditfield(app.UIFigure, 'text');
            app.XlimEditField_3.Value = '[0 6000]';
            app.YlimEditField_2Label = uilabel(app.UIFigure);
            app.YlimEditField_2Label.Text = 'Ylim';
            app.YlimEditField_2 = uieditfield(app.UIFigure, 'text');
            app.YlimEditField_2.Value = '[0 0.3]';
            app.FrekvenceButton = uibutton(app.UIFigure, 'push');
            app.FrekvenceButton.ButtonPushedFcn = createCallbackFcn(app, @Frekvence, true);
            app.FrekvenceButton.Text = 'Frekvence';
            app.RychlostfrekvenceButton = uibutton(app.UIFigure, 'push');
            app.RychlostfrekvenceButton.ButtonPushedFcn = createCallbackFcn(app, @RychlostFrekvence, true);
            app.RychlostfrekvenceButton.Text = 'Rychlost/frekvence';
            app.Ylim2EditFieldLabel = uilabel(app.UIFigure);
            app.Ylim2EditFieldLabel.Text = 'Ylim2';
            app.Ylim2EditField = uieditfield(app.UIFigure, 'text');
            app.Ylim2EditField.Value = '[0 0.03]';
            app.XlimEditField_4Label = uilabel(app.UIFigure);
            app.XlimEditField_4Label.Text = 'Xlim';
            app.XlimEditField_4 = uieditfield(app.UIFigure, 'text');
            app.XlimEditField_4.Value = '[0 6000]';
            app.YlimEditField_3Label = uilabel(app.UIFigure);
            app.YlimEditField_3Label.Text = 'Ylim';
            app.YlimEditField_3 = uieditfield(app.UIFigure, 'text');
            app.YlimEditField_3.Value = '[0 0.03]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aplikace_robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end